classdef GUI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                    matlab.ui.Figure
		FileMenu                    matlab.ui.container.Menu
		OpenMenu                    matlab.ui.container.Menu
		SaveMenu                    matlab.ui.container.Menu
		EditMenu                    matlab.ui.container.Menu
		FinishCheckBox              matlab.ui.control.CheckBox
		SelectImageSlider           matlab.ui.control.Slider
		SelectImageSliderLabel      matlab.ui.control.Label
		SelectImageDirectoryButton  matlab.ui.control.Button
		UIAxes                      matlab.ui.control.UIAxes
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Callback function: OpenMenu, SelectImageDirectoryButton
		function LoadDir(app, event)
			path = uigetdir();  % Select image directory
			files = dir(path);  % List all files in directory
			files = files(~[files.isdir]); % Remove subdirectories from list
			% Keep only .jpg files
			I = ~cellfun('isempty', regexp({files.name}, '.jpg$'));
			files = files(I);
			% Load all images
			imgs = cell(numel(files));  % preallocate image storage
			for i = 1:numel(files)
				fpath = fullfile(files(i).folder, files(i).name);
				imgs{i} = imread(fpath);
			end
			% Display first image
			imagesc(app.UIAxes, imgs{1})
		end

		% Callback function
		function OpenImage(app, event)

		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.FileMenu = uimenu(app.UIFigure);
			app.FileMenu.Text = 'File';
			app.OpenMenu = uimenu(app.FileMenu);
			app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDir, true);
			app.OpenMenu.Text = 'Open';
			app.SaveMenu = uimenu(app.FileMenu);
			app.SaveMenu.Text = 'Save';
			app.EditMenu = uimenu(app.UIFigure);
			app.EditMenu.Text = 'Edit';
			app.UIAxes = uiaxes(app.UIFigure);
			app.UIAxes.XLim = [0 1];
			app.UIAxes.XTick = [];
			app.UIAxes.YTick = [];
			app.SelectImageDirectoryButton = uibutton(app.UIFigure, 'push');
			app.SelectImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDir, true);
			app.SelectImageDirectoryButton.Text = 'Select Image Directory';
			app.SelectImageSliderLabel = uilabel(app.UIFigure);
			app.SelectImageSliderLabel.Text = 'Select Image #';
			app.SelectImageSlider = uislider(app.UIFigure);
			app.FinishCheckBox = uicheckbox(app.UIFigure);
			app.FinishCheckBox.Text = 'Finish';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = GUI

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end